classdef aeroporturi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Panel2     matlab.ui.container.Panel
        cevaLabel  matlab.ui.control.Label
        Panel      matlab.ui.container.Panel
    end


    properties (Access = private)
        MapAxes;
        T;
        ScatterPlot;
        UpdateTimer;
        Pozitie = [];
        SelectedAirports = {};
        SelectedPlot;
        HoverPlot;
        HoveredAirport = '';
        HoveredIndex = [];
        HoverLabel;
        HoverTimer;
        ZoomObj;
        PanObj;
        %Rute;
        %SelectionRadius;
    end

    methods (Access = private)

        function loadMap(app)

            app.T = readgeotable('aeroporturi.kml');
            app.MapAxes.Units = 'normalized';
            app.MapAxes.Position = [0.08, 0.12, 0.80, 0.75];
            hold(app.MapAxes,"on");
            app.ScatterPlot = geoscatter(app.MapAxes, app.T.Shape.Latitude, app.T.Shape.Longitude, 20 , 'red', "filled");
            %drawnow;
            %text(app.MapAxes, app.T.Shape.Latitude, app.T.Shape.Longitude, app.Aeroporturi, 'FontSize', 10, 'Color', 'black');
            hold(app.MapAxes, 'off');
            title(app.MapAxes, 'Aeroporturile');


        end

        function text = showToolTip(app)

            %app.T = readgeotable("aeroporturi.kml");

            pozitie_cursor = get(app.MapAxes,"CurrentPoint");
            cursor_lat = pozitie_cursor(1,2);
            cursor_lon = pozitie_cursor(1,1);

            distanta = sqrt((app.T.Shape.Latitude - cursor_lat).^2 + (app.T.Shape.Longitude - cursor_lon).^2);

            toleranta = 0.1;

            [distanta_min,index] = min(distanta);

            if distanta_min < toleranta
                text = app.T.name(index);
            else
                text = '';
            end

            app.MapAxes.Tooltip = text;

        end

        function actualizareHarta(app,~,~)

            schimbareInformatiiHarta(app);

        end

        function schimbareInformatiiHarta(app)

            %app.T = readgeotable('aplicatie\aeroporturi.kml');
            [latlim,lonlim] = geolimits(app.MapAxes);
            vizualizare_puncte = (app.T.Shape.Latitude >= latlim(1) & app.T.Shape.Latitude <= latlim(2)) &...
                (app.T.Shape.Longitude >= lonlim(1) & app.T.Shape.Longitude <= lonlim(2));

            lat_filtrat = app.T.Shape.Latitude(vizualizare_puncte);
            lon_filtrat = app.T.Shape.Longitude(vizualizare_puncte);
            nume_filtrat = app.T.Name(vizualizare_puncte);

            app.ScatterPlot.XData = lat_filtrat;
            app.ScatterPlot.YData = lon_filtrat;

            app.ScatterPlot.DataTipTemplate.DataTipRows(1) = dataTipTextRow('',nume_filtrat);
            app.ScatterPlot.DataTipTemplate.DataTipRows(2) = dataTipTextRow('Latitudine: ',lat_filtrat);
            app.ScatterPlot.DataTipTemplate.DataTipRows(3) = dataTipTextRow('Longitudine: ',lon_filtrat);

        end

        function onMouseMove(app, ~)
            % Get current mouse position
            axPos = getpixelposition(app.MapAxes, true);
            figPoint = app.UIFigure.CurrentPoint;

            if figPoint(1) >= axPos(1) && figPoint(1) <= (axPos(1) + axPos(3)) && ...
                    figPoint(2) >= axPos(2) && figPoint(2) <= (axPos(2) + axPos(4))

                cp = app.MapAxes.CurrentPoint;
                lon = cp(1,1);  
                lat = cp(1,2);  

                distances = sqrt((app.T.Shape.Latitude - lat).^2 + (app.T.Shape.Longitude - lon).^2);

                [latlim, lonlim] = geolimits(app.MapAxes);
                map_width = lonlim(2) - lonlim(1);
                tol = map_width * 0.01;

                [min_dist, idx] = min(distances);

                if min_dist <= tol
                    fields = fieldnames(app.T);
                    
                    if ismember('Name', fields)
                        hoveredName = app.T.Name{idx};
                    elseif ismember('name', fields)
                        hoveredName = app.T.name{idx};
                    else
                        stringFields = find(cellfun(@(x) ischar(app.T.(x){1}) || isstring(app.T.(x){1}), fields));
                        if ~isempty(stringFields)
                            nameField = fields{stringFields(1)};
                            hoveredName = app.T.(nameField){idx};
                        else
                            hoveredName = ['Airport ', num2str(idx)];
                        end
                    end

                    if isfield(app, 'HoverLabel') && isvalid(app.HoverLabel)
                        app.HoverLabel.Text = ['Hovered: ', hoveredName];
                        app.HoverLabel.Visible = 'on';
                    else
                        app.HoverLabel = uilabel(app.Panel);
                        app.HoverLabel.Position = [10, 10, 300, 22];
                        app.HoverLabel.Text = ['Hovered: ', hoveredName];
                        app.HoverLabel.FontWeight = 'bold';
                        app.HoverLabel.BackgroundColor = [1 1 0.8];
                    end

                 
                    hold(app.MapAxes, 'on');
                    if isfield(app, 'HoverPlot') && ~isempty(app.HoverPlot) && isvalid(app.HoverPlot)
                        delete(app.HoverPlot);
                    end
                    app.HoverPlot = geoscatter(app.MapAxes, app.T.Shape.Latitude(idx), ...
                        app.T.Shape.Longitude(idx), 30, 'yellow', 'filled');
                    hold(app.MapAxes, 'off');

                    app.HoveredAirport = hoveredName;
                    app.HoveredIndex = idx;
                else
                    if isfield(app, 'HoverLabel') && isvalid(app.HoverLabel)
                        app.HoverLabel.Visible = 'off';
                    end

                    if isfield(app, 'HoverPlot') && ~isempty(app.HoverPlot) && isvalid(app.HoverPlot)
                        delete(app.HoverPlot);
                        app.HoverPlot = [];
                    end

                    app.HoveredAirport = '';
                    app.HoveredIndex = [];
                end
            end
        end

        function onKeyPress(app, event)
            if ~isempty(app.HoveredAirport) && ~isempty(app.HoveredIndex)
                if strcmp(event.Key, 'space') || strcmp(event.Key, 'return') || strcmp(event.Key, 's')
                    if isempty(app.SelectedAirports)
                        app.SelectedAirports = {};
                    end

                    if ~any(strcmp(app.SelectedAirports, app.HoveredAirport))
                        app.SelectedAirports{end+1} = app.HoveredAirport;

                        hold(app.MapAxes, 'on');
                        if ~isfield(app, 'SelectedPlot') || isempty(app.SelectedPlot)
                            app.SelectedPlot = geoscatter(app.MapAxes, app.T.Shape.Latitude(app.HoveredIndex), ...
                                app.T.Shape.Longitude(app.HoveredIndex), 40, 'blue', 'filled');
                        else
                            if isvalid(app.SelectedPlot)
                                newLat = [app.SelectedPlot.XData, app.T.Shape.Latitude(app.HoveredIndex)];
                                newLon = [app.SelectedPlot.YData, app.T.Shape.Longitude(app.HoveredIndex)];
                                app.SelectedPlot.XData = newLat;
                                app.SelectedPlot.YData = newLon;
                            else
                                app.SelectedPlot = geoscatter(app.MapAxes, app.T.Shape.Latitude(app.HoveredIndex), ...
                                    app.T.Shape.Longitude(app.HoveredIndex), 40, 'blue', 'filled');
                            end
                        end
                        hold(app.MapAxes, 'off');

                        displayText = '';
                        for i = 1:length(app.SelectedAirports)
                            if i > 1
                                displayText = [displayText, newline, app.SelectedAirports{i}];
                            else
                                displayText = app.SelectedAirports{i};
                            end
                        end
                        app.Label.Text = displayText;

                        disp(['Added airport to selection: ', app.HoveredAirport]);
                    else
                        disp(['Airport already selected: ', app.HoveredAirport]);
                    end
                end
            end
        end

        function checkForHoveredAirports(app)

            if ~isvalid(app.UIFigure) || ~isvalid(app.MapAxes)
                return;
            end

            asPos = getpixelposition(app.MapAxes,true);
            figPoint = app.UIFigure.CurrentPoint;

            if figPoint(1) >= asPos(1) && figPoint(2) <= (asPos(1) + asPos(3)) ...
                    && figPoint(2) >= asPos(2) && figPoint(2) <= (asPos(2) + asPos(3))

                cp = app.MapAxes.CurrentPoint;

                if isempty(cp)
                    return;
                end

                lat = cp(1,2);
                lon = cp(1,1);

                if (~isempty(app.ZoomObj) && strcmp(app.ZoomObj.Enable,'on') && app.ZoomObj.Direction ~= 0) || ...
                        (~isempty(app.PanObj) && strcmp(app.PanObj.Enable,'on') && app.PanObj.Enable ~= 0)

                    clearHoverIndication(app);
                    return;

                end
                distances = sqrt((app.T.Shape.Latitude - lat).^2 + (app.T.Shape.Longitude - lon).^2);

                [latlim, lonlim] = geolimits(app.MapAxes);
                map_width = lonlim(2) - lonlim(1);
                tol = map_width * 0.01;

                [min_dist, idx] = min(distances);

                if min_dist <= tol

                    fields = fieldnames(app.T);

                    if ismember('Name', fields)
                        hoveredName = app.T.Name{idx};
                    elseif ismember('name', fields)
                        hoveredName = app.T.name{idx};
                    else
                        stringFields = find(cellfun(@(x) ischar(app.T.(x){1}) || isstring(app.T.(x){1}), fields));
                        if ~isempty(stringFields)
                            nameField = fields{stringFields(1)};
                            hoveredName = app.T.(nameField){idx};
                        else
                            hoveredName = ['Airport ', num2str(idx)];
                        end
                    end

                    app.HoverLabel.Text = ['Hovered: ', hoveredName, ' (Press Space to select)'];

                    if isempty(app.HoveredIndex) || app.HoveredIndex ~= idx
                        hold(app.MapAxes, 'on');
                        if isfield(app, 'HoverPlot') && ~isempty(app.HoverPlot) && isvalid(app.HoverPlot)
                            delete(app.HoverPlot);
                        end
                        app.HoverPlot = geoscatter(app.MapAxes, app.T.Shape.Latitude(idx), ...
                            app.T.Shape.Longitude(idx), 30, 'yellow', 'filled');
                        hold(app.MapAxes, 'off');
                    end

                    app.HoveredAirport = hoveredName;
                    app.HoveredIndex = idx;
                else
                    clearHoverIndication(app);
                end
            else
                clearHoverIndication(app);
            end

        end

        function clearHoverIndication(app)

            if isfield(app,'HoverPlot') && ~isempty(app.HoverPlot) && isvalid(app.HoverPlot)
                
                delete(app.HoverPlot);
                app.HoverPlot = [];

            end

            app.HoverLabel = "";
            app.HoverIndex = [];

        end

        function cleanup(app,event)
            
            if isfield(app,'UpdateTimer') && ~isempty(app.Updatetimer) && isvalid(app.UpdateTimer)

                stop(app.UpdateTimer);
                delete(app.UpdateTimer);

            end

            if isfield(app,'HoverTImer') && ~isempty(app.HoverTimer) && isvalid(app.HoverTimer)
                
                stop(app.HoverTimer);
                delete(app.HoverTimer);

            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            drawnow;
            app.UIFigure.WindowState = "maximized";
            app.UIFigure.Name = "Optimizare zboruri";
            app.Panel.Units = 'pixels';
            PanelSizeChanged(app);
            tempAx = geoaxes(app.Panel);
            tempAx.Parent = app.Panel;
            app.MapAxes = tempAx;
            geobasemap(app.MapAxes, 'streets');
            geolimits(app.MapAxes,'auto');

            app.ZoomObj = zoom(app.UIFigure);
            app.ZoomObj.Enable = 'on';
            app.PanObj = pan(app.UIFigure);
            app.PanObj.Enable = 'on';

            loadMap(app);

            app.HoverTimer = timer('ExecutionMode','fixedRate','Period',0.2, ...
                'TimerFcn',@(~,~)checkForHoveredAirports(app));

            start(app.HoverTimer);

            app.UIFigure.WindowButtonMotionFcn = @(src, event)onMouseMove(app);

            addlistener(app.MapAxes,'SizeChanged',@(src,event) PanelSizeChanged(app));
            app.UpdateTimer = timer('ExecutionMode','fixedRate','Period', ...
                0.5,'TimerFcn',@(~,~)actualizareHarta(app));

            start(app.UpdateTimer);

            app.HoverLabel = uilabel(app.Panel);
            app.HoverLabel.Position = [10 10 300 22];
            app.HoverLabel.Text = "Mutati cursorul asupra unui aeroport si apasati space pentru a-l selecta";
            app.HoverLabel.FontWeight = 'bold';
            app.HoverLabel.BackgroundColor = [1 1 0.8];


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            cleanup(app);
            delete(app);

        end

        % Size changed function: Panel
        function PanelSizeChanged(app, event)

            figwidth = app.UIFigure.Position(3);
            figheight = app.UIFigure.Position(4);

            panelwidth = figwidth * 0.8;
            panelheight = figheight * 0.8;

            panelX = (figwidth - panelwidth) / 2 - 100;
            panelY = (figheight - panelheight) / 2;

            app.Panel.Position = [panelX,panelY,panelwidth,panelheight];

        end

        % Button down function: UIFigure
        function onMapClick(app, event)

            cp = app.MapAxes.CurrentPoint;
            lon = cp(1,1); 
            lat = cp(1,2); 

            axPos = getpixelposition(app.MapAxes, true);
            figPoint = app.UIFigure.CurrentPoint;

            if figPoint(1) >= axPos(1) && figPoint(1) <= (axPos(1) + axPos(3)) && ...
                    figPoint(2) >= axPos(2) && figPoint(2) <= (axPos(2) + axPos(4))

                clickX = figPoint(1) - axPos(1);
                clickY = figPoint(2) - axPos(2);

                [latlim, lonlim] = geolimits(app.MapAxes);
                axWidth = axPos(3);
                axHeight = axPos(4);

                normLon = (app.T.Shape.Longitude - lonlim(1)) / (lonlim(2) - lonlim(1));
                normLat = (app.T.Shape.Latitude - latlim(1)) / (latlim(2) - latlim(1));
                markerX = normLon * axWidth;
                markerY = (1 - normLat) * axHeight;

                distances_px = sqrt((markerX - clickX).^2 + (markerY - clickY).^2);

                tol_px = 0.02 * min(axWidth, axHeight);

                idx = find(distances_px < tol_px);

                if ~isempty(idx)
                    [~, closestIdx] = min(distances_px(idx));
                    actualIdx = idx(closestIdx);
                    fields = fieldnames(app.T);
                    disp('Available fields in app.T:');
                    disp(fields);

                    if ismember('Name', fields)
                        selectedName = app.T.Name{actualIdx};
                    elseif ismember('name', fields)
                        selectedName = app.T.name{actualIdx};
                    else
                        stringFields = find(cellfun(@(x) ischar(app.T.(x){1}) || isstring(app.T.(x){1}), fields));
                        if ~isempty(stringFields)
                            nameField = fields{stringFields(1)};
                            selectedName = app.T.(nameField){actualIdx};
                        else
                            selectedName = ['Airport ', num2str(actualIdx)];
                        end
                    end

                    disp(['Selected airport index: ', num2str(actualIdx)]);
                    disp(['Selected airport name: ', selectedName]);

                    if isempty(app.SelectedAirports)
                        app.SelectedAirports = {};
                    end

                    if ~any(strcmp(app.SelectedAirports, selectedName))
                        app.SelectedAirports{end+1} = selectedName;

                        hold(app.MapAxes, 'on');
                        if ~isfield(app, 'SelectedPlot') || isempty(app.SelectedPlot)
                            app.SelectedPlot = geoscatter(app.MapAxes, app.T.Shape.Latitude(actualIdx), ...
                                app.T.Shape.Longitude(actualIdx), 40, 'blue', 'filled');
                        else
                            newLat = [app.SelectedPlot.XData, app.T.Shape.Latitude(actualIdx)];
                            newLon = [app.SelectedPlot.YData, app.T.Shape.Longitude(actualIdx)];
                            app.SelectedPlot.XData = newLat;
                            app.SelectedPlot.YData = newLon;
                        end
                        hold(app.MapAxes, 'off');
                    end

                    if ~isempty(app.SelectedAirports)
                        formattedText = '';
                        for i = 1:length(app.SelectedAirports)
                            if i > 1
                                formattedText = [formattedText, newline, app.SelectedAirports{i}];
                            else
                                formattedText = app.SelectedAirports{i};
                            end
                        end
                        app.cevaLabel.Text = formattedText;
                    else
                        app.cevaLabel.Text = '';
                    end
   
                    drawnow;
                else
                    disp('No airport was selected.');
                end
            end

        end

        % Size changed function: UIFigure
        function UpdateSelectionRadius(app, event)

            [latlim, lonlim] = geolimits(app.MapAxes);
            lat_range = latlim(2) - latlim(1);
            lon_range = lonlim(2) - lonlim(1);
            max_range = max(lat_range,lon_range);
            app.SelectionRadius = deg2km(max_range);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UpdateSelectionRadius, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @onMapClick, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel.SizeChangedFcn = createCallbackFcn(app, @PanelSizeChanged, true);
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.cevaLabel = uilabel(app.Panel2);
            app.cevaLabel.Text = 'ceva';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aeroporturi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end